classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        HOMETab                        matlab.ui.container.Tab
        SaveButton_3                   matlab.ui.control.Button
        ResetButton_3                  matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        GammaTab                       matlab.ui.container.Tab
        ApplyGammaButton               matlab.ui.control.Button
        GammaValueEditField            matlab.ui.control.EditField
        GammaValueEditFieldLabel       matlab.ui.control.Label
        LogTab                         matlab.ui.container.Tab
        ApplyLogButton                 matlab.ui.control.Button
        AddValueEditField              matlab.ui.control.EditField
        AddValueEditFieldLabel         matlab.ui.control.Label
        Bright1Tab                     matlab.ui.container.Tab
        ApplyAddSubButton              matlab.ui.control.Button
        AddSubEditField                matlab.ui.control.EditField
        AddSubEditFieldLabel           matlab.ui.control.Label
        Bright2Tab                     matlab.ui.container.Tab
        ApplyMulDevButton              matlab.ui.control.Button
        MulDevEditField                matlab.ui.control.EditField
        MulDevEditFieldLabel           matlab.ui.control.Label
        FourierTransformTab            matlab.ui.container.Tab
        ApplyFourierButton             matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        InverseFourierTransformButton  matlab.ui.control.RadioButton
        FourierTransformButton         matlab.ui.control.RadioButton
        NegativeTab                    matlab.ui.container.Tab
        Binary2NegativeButton          matlab.ui.control.Button
        RGB2NegativeButton             matlab.ui.control.Button
        UploadImageButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        HistogramTab                   matlab.ui.container.Tab
        RestartButton_2                matlab.ui.control.Button
        UploadImageButton_3            matlab.ui.control.Button
        ContrastStretchingPanel        matlab.ui.container.Panel
        NewMinEditField                matlab.ui.control.EditField
        NewMinEditFieldLabel           matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        NewMaxEditField                matlab.ui.control.EditField
        NewMaxEditFieldLabel           matlab.ui.control.Label
        ApplyHistogramEqButton         matlab.ui.control.Button
        ShowHistogramButton            matlab.ui.control.Button
        UIAxesOutputHisto              matlab.ui.control.UIAxes
        UIAxesinputHisto               matlab.ui.control.UIAxes
        FiltersTab                     matlab.ui.container.Tab
        SaveButton_2                   matlab.ui.control.Button
        ResetButton_2                  matlab.ui.control.Button
        RestartButton_3                matlab.ui.control.Button
        FilterPanel                    matlab.ui.container.Panel
        LineDetectionDropDown          matlab.ui.control.DropDown
        LineDetectionDropDownLabel     matlab.ui.control.Label
        ApplyFilterDropDown            matlab.ui.control.DropDown
        ApplyFilterDropDownLabel       matlab.ui.control.Label
        EditFieldmask_9                matlab.ui.control.NumericEditField
        EditFieldmask_8                matlab.ui.control.NumericEditField
        EditFieldmask_7                matlab.ui.control.NumericEditField
        EditFieldmask_6                matlab.ui.control.NumericEditField
        EditFieldmask_5                matlab.ui.control.NumericEditField
        EditFieldmask_4                matlab.ui.control.NumericEditField
        EditFieldmask_3                matlab.ui.control.NumericEditField
        EditFieldmask_2                matlab.ui.control.NumericEditField
        EditFieldmask                  matlab.ui.control.NumericEditField
        UploadImageButton_2            matlab.ui.control.Button
        TabGroup3                      matlab.ui.container.TabGroup
        MinFilterTab                   matlab.ui.container.Tab
        ValueEditField                 matlab.ui.control.NumericEditField
        ValueEditFieldLabel            matlab.ui.control.Label
        MaxFilterTab                   matlab.ui.container.Tab
        ValueEditField_2               matlab.ui.control.NumericEditField
        ValueEditField_2Label          matlab.ui.control.Label
        MedianFilterTab                matlab.ui.container.Tab
        ValueEditField_3               matlab.ui.control.NumericEditField
        ValueEditField_3Label          matlab.ui.control.Label
        MidPointFilterTab              matlab.ui.container.Tab
        ValueEditField_4               matlab.ui.control.NumericEditField
        ValueEditField_4Label          matlab.ui.control.Label
        GaussLowTab                    matlab.ui.container.Tab
        RadiusEditField_2              matlab.ui.control.NumericEditField
        RadiusEditField_2Label         matlab.ui.control.Label
        GaussHighTab                   matlab.ui.container.Tab
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        BWLowTab                       matlab.ui.container.Tab
        OrderEditField                 matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        RadiusEditField_3              matlab.ui.control.NumericEditField
        RadiusEditField_3Label         matlab.ui.control.Label
        BWHighTab                      matlab.ui.container.Tab
        OrderEditField_2               matlab.ui.control.NumericEditField
        OrderEditField_2Label          matlab.ui.control.Label
        RadiusEditField_4              matlab.ui.control.NumericEditField
        RadiusEditField_4Label         matlab.ui.control.Label
        IdealLPTab_2                   matlab.ui.container.Tab
        RadiusEditField_5              matlab.ui.control.NumericEditField
        RadiusEditField_5Label         matlab.ui.control.Label
        IdealHPTab                     matlab.ui.container.Tab
        RadiusEditField_6              matlab.ui.control.NumericEditField
        RadiusEditField_6Label         matlab.ui.control.Label
        ApplyFilterButton              matlab.ui.control.Button
        UIAxesinputFilter              matlab.ui.control.UIAxes
        UIAxesOutputFilter             matlab.ui.control.UIAxes
        NoiseConvertTab                matlab.ui.container.Tab
        SaveButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        RestartButton_4                matlab.ui.control.Button
        UploadImageButton_4            matlab.ui.control.Button
        TabGroup4                      matlab.ui.container.TabGroup
        GaussianNoiseTab               matlab.ui.container.Tab
        AddNoiseButton                 matlab.ui.control.Button
        SegmaEditField                 matlab.ui.control.NumericEditField
        SegmaEditFieldLabel            matlab.ui.control.Label
        MeuEditField                   matlab.ui.control.NumericEditField
        MeuEditFieldLabel              matlab.ui.control.Label
        RayLeighNoiseTab               matlab.ui.container.Tab
        AddNoiseButton_2               matlab.ui.control.Button
        BEditField                     matlab.ui.control.NumericEditField
        BEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        AEditFieldLabel                matlab.ui.control.Label
        GammaNoiseTab                  matlab.ui.container.Tab
        AddNoiseButton_3               matlab.ui.control.Button
        BEditField_2                   matlab.ui.control.NumericEditField
        BEditField_2Label              matlab.ui.control.Label
        AEditField_2                   matlab.ui.control.NumericEditField
        AEditField_2Label              matlab.ui.control.Label
        UniformNoiseTab                matlab.ui.container.Tab
        PercEditField                  matlab.ui.control.NumericEditField
        PercEditFieldLabel             matlab.ui.control.Label
        BEditField_3                   matlab.ui.control.NumericEditField
        BEditField_3Label              matlab.ui.control.Label
        AEditField_3                   matlab.ui.control.NumericEditField
        AEditField_3Label              matlab.ui.control.Label
        AddNoiseButton_4               matlab.ui.control.Button
        SaltPepperTab                  matlab.ui.container.Tab
        AddNoiseButton_5               matlab.ui.control.Button
        PbEditField                    matlab.ui.control.NumericEditField
        PbEditFieldLabel               matlab.ui.control.Label
        PaEditField                    matlab.ui.control.NumericEditField
        PaEditFieldLabel               matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ChosetheProcessDropDown        matlab.ui.control.DropDown
        ChosetheProcessDropDownLabel   matlab.ui.control.Label
        ConvertButton                  matlab.ui.control.Button
        UIAxesOutputNoise_2            matlab.ui.control.UIAxes
        UIAxesInputNoise               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            global image1;
            [filename, pathname] = uigetfile({'*.*'},"open file");
            fullpathname = strcat( pathname,filename);
            image1 = imread(fullpathname);
            imshow(image1,'parent',app.UIAxes);


        end

        % Button pushed function: UploadImageButton_2
        function UploadImageButton_2Pushed(app, event)
            global image1;
            [filename, pathname] = uigetfile({'*.*'},"open file");
            fullpathname = strcat( pathname,filename);
            image1 = imread(fullpathname);
            imshow(image1,'parent',app.UIAxesinputFilter);
        end

        % Button pushed function: UploadImageButton_3
        function UploadImageButton_3Pushed(app, event)
              global image1;
            [filename, pathname] = uigetfile({'*.*'},"open file");
            fullpathname = strcat( pathname,filename);
            image1 = imread(fullpathname);
            imshow(image1,'parent',app.UIAxesinputHisto);
        end

        % Button pushed function: UploadImageButton_4
        function UploadImageButton_4Pushed(app, event)
            global image1;
            [filename, pathname] = uigetfile({'*.*'},"open file");
            fullpathname = strcat( pathname,filename);
            image1 = imread(fullpathname);
            imshow(image1,'parent',app.UIAxesInputNoise);
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
              global image1;
              title(app.UIAxesOutputHisto,'Histogram');
            bar(app.UIAxesOutputHisto,Histogram(image1));
        end

        % Button pushed function: ApplyHistogramEqButton
        function ApplyHistogramEqButtonPushed2(app, event)
             global image1;
              title(app.UIAxesOutputHisto,'Histogram Equalization');
             bar(app.UIAxesOutputHisto,HistogramEq(image1));
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
             global image1;
            title(app.UIAxes2,'Contrast Stretching');
            newMin=str2double(app.NewMinEditField.Value);
            newMax=str2double(app.NewMaxEditField.Value);
            result=Contrast_Stretching(image1,newMin,newMax);
            imshow(result,'parent',app.UIAxesOutputHisto);
        end

        % Button pushed function: ApplyGammaButton
        function ApplyGammaButtonPushed(app, event)
            
            global image1;
            GammaVal = str2double(app.GammaValueEditField.Value);
            imshow(Gamma(image1,GammaVal),'parent',app.UIAxes2);
            
        end

        % Button pushed function: ApplyLogButton
        function ApplyLogButtonPushed(app, event)
            
            global image1;
            logConst = str2double(app.AddValueEditField.Value);
            result = Log(image1,logConst);
            imshow(result,'parent',app.UIAxes2);

            
        end

        % Button pushed function: ApplyAddSubButton
        function ApplyAddSubButtonPushed(app, event)
            global image1;
            addsub=app.AddSubEditField.Value;
            addsub=str2double(addsub);
            [row , col, depth] = size(image1);
            BrightImage = zeros(row,col,depth);
            BrightImage = im2double(BrightImage);
            
             BrightImage = image1 + (addsub);
                
              for r = 1:row
                    for c = 1:col
                        for dep =1:depth
                            if BrightImage(r, c, dep) > 255
                                BrightImage(r, c, dep) = 255;
                            elseif BrightImage(r, c, dep) < 0
                                BrightImage(r, c, dep) = 0;
                            end
                        end
                    end
              end
            BrightImage = im2uint8(BrightImage);
            imshow(BrightImage,'parent',app.UIAxes2);
        end

        % Button pushed function: ApplyMulDevButton
        function ApplyMulDevButtonPushed(app, event)
              global image1;
            mulDev=app.AddSubEditField.Value;
            mulDev=str2double(mulDev);
            [row , col, depth] = size(image1);
            BrightImage = zeros(row,col,depth);
            BrightImage = im2double(BrightImage);
            
             BrightImage = image1 * (mulDev);
                
              for r = 1:row
                    for c = 1:col
                        for dep =1:depth
                            if BrightImage(r, c, dep) > 255
                                BrightImage(r, c, dep) = 255;
                            elseif BrightImage(r, c, dep) < 0
                                BrightImage(r, c, dep) = 0;
                            end
                        end
                    end
              end
            BrightImage = im2uint8(BrightImage);
            imshow(BrightImage,'parent',app.UIAxes2);
        end

        % Button pushed function: ApplyFourierButton
        function ApplyFourierButtonPushed(app, event)
            global image1;
            if(app.FourierTransformButton.Value)
                gray_img=rgb2gray(image1);
                F= fft2(gray_img) ;
                fshift = fftshift(F);
               log_img = log(1+abs(fshift));
               F = ifftshift(fshift);
               f = ifft2(F);
               imshow (f,'Parent',app.UIAxes2);
            elseif(app.InverseFourierTransformButton.Value)
               
                f1=fft2(image1);
                f2=fftshift(f1);
                n=abs(f2);
                n=log(1+n);
                n=mat2gray(n);
                %figure,imshow(n);
                f3=fftshift(f2);
                f3=ifft2(f3);
                f3=abs(f3);
                f3=log(f3);
                f3=mat2gray(f3);
               
               imshow (f3,'Parent',app.UIAxes2);
            end
        end

        % Callback function
        function AddNoiseButtonPushed(app, event)
            global image1;
             title(app.UIAxesOutputNoise_2,'Gaussian Noise');
            m = app.MeuEditField.Value;
            s = app.SegmaEditField.Value;
            noiseImage = Gaussian_Normal_Noise(image1,m,s);
            imshow(noiseImage,'parent',app.UIAxesOutputNoise_2);
            
        end

        % Button pushed function: AddNoiseButton_2
        function AddNoiseButton_2Pushed(app, event)
           global image1;
           title(app.UIAxesOutputNoise_2,'RayLeigh Noise');
            a = app.AEditField.Value;
            b = app.BEditField.Value;
            noiseImage = RayLeigh_Noise(image1,a,b);
            imshow(noiseImage,'parent',app.UIAxesOutputNoise_2);
        end

        % Button pushed function: AddNoiseButton_3
        function AddNoiseButton_3Pushed(app, event)
             global image1;
           title(app.UIAxesOutputNoise_2,'Gamma Noise');
            a = app.AEditField_2.Value;
            b = app.BEditField_2.Value;
            noiseImage =gamma_noise(image1,a,b);
            imshow(noiseImage,'parent',app.UIAxesOutputNoise_2);
        end

        % Button pushed function: AddNoiseButton_4
        function AddNoiseButton_4Pushed(app, event)
             global image1;
           title(app.UIAxesOutputNoise_2,'Uniform Noise');
            a = app.AEditField_3.Value;
            b = app.BEditField_3.Value;
            perc = app.PercEditField.Value;
            noiseImage = uniform_noise(image1,a,b,perc);
            imshow(noiseImage,'parent',app.UIAxesOutputNoise_2);
        end

        % Button pushed function: AddNoiseButton_5
        function AddNoiseButton_5Pushed(app, event)
             global image1;
           title(app.UIAxesOutputNoise_2,'Salt & Pepper Noise');
            Pa = app.PaEditField.Value;
            Pb = app.PbEditField.Value;
            noiseImage = Salt_Peppers_Noise(image1,Pa,Pb);
            imshow(noiseImage,'parent',app.UIAxesOutputNoise_2);
        end

        % Button pushed function: RGB2NegativeButton
        function RGB2NegativeButtonPushed(app, event)
            global image1;
            negImg = Negative(image1);
            imshow(negImg,'Parent',app.UIAxes2);
        end

        % Button pushed function: Binary2NegativeButton
        function Binary2NegativeButtonPushed(app, event)
             global image1;
            negImg = BinaryNegative(image1);
            imshow(negImg,'Parent',app.UIAxes2);
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
           global image1;
            minval= app.ValueEditField.Value;
            maxval= app.ValueEditField_2.Value;
            medval= app.ValueEditField_3.Value;
            midval = app.ValueEditField_4.Value;
            glval = app.RadiusEditField_2.Value;
            ghval = app.RadiusEditField.Value;
            bwl = app.RadiusEditField_3.Value;
            bwlo = app.OrderEditField.Value;
            bwh = app.RadiusEditField_4.Value;
            bwho = app.OrderEditField_2.Value;
            idlp = app.RadiusEditField_5.Value;
            idhp = app.RadiusEditField_6.Value;

            if(app.ValueEditField.Value)
                 
                 title(app.UIAxesOutputFilter,'Min Filter');
                result = min_filter(image1,minval);
                imshow(result,'parent',app.UIAxesOutputFilter);
                       

            elseif(app.ValueEditField_2.Value)
               
                title(app.UIAxesOutputFilter,'Max Filter');
                result = max_filter(image1,maxval);
                imshow(result,'parent',app.UIAxesOutputFilter);
                
            elseif(app.ValueEditField_3.Value)
                 title(app.UIAxesOutputFilter,'Median Filter');
                result = median_filter(image1,medval);
                imshow(result,'parent',app.UIAxesOutputFilter);
            elseif(app.ValueEditField_4.Value)
                 title(app.UIAxesOutputFilter,'MidPoint Filter');
                result = mid_point(image1,midval);
                imshow(result,'parent',app.UIAxesOutputFilter);
            elseif(app.RadiusEditField_2.Value)
                 title(app.UIAxesOutputFilter,'Gaussian Low Filter');
                result = Gaussain_low(image1,glval);
                imshow(result,'parent',app.UIAxesOutputFilter);
            elseif (app.RadiusEditField.Value)
                 title(app.UIAxesOutputFilter,'Gaussian High Filter');
                    result = Gaussain_high(image1,ghval);
                imshow(result,'parent',app.UIAxesOutputFilter);
            elseif(app.RadiusEditField_3.Value)
                 title(app.UIAxesOutputFilter,'ButterWorth Low Filter');
                   result = Butter_worth_low(image1,bwl,bwlo);
                imshow(result,'parent',app.UIAxesOutputFilter);
            elseif(app.RadiusEditField_4.Value)
                     title(app.UIAxesOutputFilter,'ButterWorth High Filter');
                   result = Butter_worth_high(image1,bwh,bwho);
                imshow(result,'parent',app.UIAxesOutputFilter);
            elseif(app.RadiusEditField_5.Value)
                  title(app.UIAxesOutputFilter,'Ideal LowPass Filter');
                  result = ideal_lowpass(image1,idlp);
                imshow(result,'parent',app.UIAxesOutputFilter);
             elseif(app.RadiusEditField_6.Value)
                 title(app.UIAxesOutputFilter,'Ideal HighPass Filter');
                  result = ideal_highpass(image1,idhp);
                imshow(result,'parent',app.UIAxesOutputFilter);
                
            end
        end

        % Value changed function: ApplyFilterDropDown
        function ApplyFilterDropDownValueChanged(app, event)
            global image1;
            val1 = app.EditFieldmask.Value;
            val2 = app.EditFieldmask_2.Value;
            val3= app.EditFieldmask_3.Value;
            val4 = app.EditFieldmask_4.Value;
            val5 = app.EditFieldmask_5.Value;
            val6 = app.EditFieldmask_6.Value;
            val7 = app.EditFieldmask_7.Value;
            val8 = app.EditFieldmask_8.Value;
            val9= app.EditFieldmask_9.Value;
            filter = [val1 val2 val3; val4 val5 val6; val7 val8 val9];
            
            
            switch app.ApplyFilterDropDown.Value
                case 'Correlation'
                    output = Correlation_filter(image1,filter);
                    title(app.UIAxesOutputFilter,'Correlation');
                    imshow(output,'parent',app.UIAxesOutputFilter);
                case 'Point Detection'
                    output = PointDetection(image1,filter);
                    title(app.UIAxesOutputFilter,'Point Detection');
                    imshow(output,'parent',app.UIAxesOutputFilter);
                case 'Point Sharpening'
                     output = PointSharpening(image1,filter);
                    title(app.UIAxesOutputFilter,'Point Sharpening');
                    imshow(output,'parent',app.UIAxesOutputFilter);
                case 'Weight Filter'
                     output = weightFilter(image1,filter);
                    title(app.UIAxesOutputFilter,'Weight Filter');
                    imshow(output,'parent',app.UIAxesOutputFilter);
            end
            
        end

        % Value changed function: LineDetectionDropDown
        function LineDetectionDropDownValueChanged(app, event)

              global image1;
            val1 = app.EditFieldmask.Value;
            val2 = app.EditFieldmask_2.Value;
            val3= app.EditFieldmask_3.Value;
            val4 = app.EditFieldmask_4.Value;
            val5 = app.EditFieldmask_5.Value;
            val6 = app.EditFieldmask_6.Value;
            val7 = app.EditFieldmask_7.Value;
            val8 = app.EditFieldmask_8.Value;
            val9= app.EditFieldmask_9.Value;
            filter = [val1 val2 val3; val4 val5 val6; val7 val8 val9];
            
            
            switch app.LineDetectionDropDown.Value
                case 'Left'
                    output = line_detect_left(image1,filter);
                    title(app.UIAxesOutputFilter,'Line Left Detection');
                    imshow(output,'parent',app.UIAxesOutputFilter);
                case 'Right'
                    output = line_detect_right(image1,filter);
                    title(app.UIAxesOutputFilter,'Line Right Detection');
                    imshow(output,'parent',app.UIAxesOutputFilter);
                case 'Vertical'
                     output = line_detect_vertical(image1,filter);
                    title(app.UIAxesOutputFilter,'Line Vertical Detection');
                    imshow(output,'parent',app.UIAxesOutputFilter);
                case 'Weight Filter'
                     output = line_detect_horizontal(image1,filter);
                    title(app.UIAxesOutputFilter,'Line Horizontal Detection');
                    imshow(output,'parent',app.UIAxesOutputFilter);
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.UIFigure.Visible='off';
            app1();
            close(app.UIFigure)
        end

        % Button pushed function: RestartButton_2
        function RestartButton_2Pushed(app, event)
             app.UIFigure.Visible='off';
            app1();
            close(app.UIFigure)
        end

        % Button pushed function: RestartButton_3
        function RestartButton_3Pushed(app, event)
             app.UIFigure.Visible='off';
            app1();
            close(app.UIFigure)
        end

        % Button pushed function: RestartButton_4
        function RestartButton_4Pushed(app, event)
             app.UIFigure.Visible='off';
            app1();
            close(app.UIFigure)
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            global image1;
            image=image1;
            
            switch app.ChosetheProcessDropDown.Value
                case 'RGB2GREY'
                    title(app.UIAxesOutputNoise_2,'RGB2GREY');
                    result = RGB2GRAY(image,1);
                    imshow(result,'parent',app.UIAxesOutputNoise_2);
                case 'RGB2BINARY'
                    title(app.UIAxesOutputNoise_2,'RGB2BINARY');
                    result = RGB2BINARY(image);
                    imshow(result,'parent',app.UIAxesOutputNoise_2);
                case 'GREY2BINARY'
                    title(app.UIAxesOutputNoise_2,'GREY2BINARY');
                    result = GRAY2BINARY(image);
                    imshow(result,'parent',app.UIAxesOutputNoise_2);
            
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             global image1;
            image=image1;
            
           imshow(image,'parent',app.UIAxesOutputNoise_2);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
             global image1;
            image=image1;
            
           imshow(image,'parent',app.UIAxesOutputFilter);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
             global image1;
            image=image1;
            
           imshow(image,'parent',app.UIAxes2);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
              [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxesOutputNoise_2);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxesOutputFilter);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxes2);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup2);
            app.HOMETab.Title = 'HOME';
            app.UIAxes2 = uiaxes(app.HOMETab);
            title(app.UIAxes2, 'Processed Image')
            app.UIAxes = uiaxes(app.HOMETab);
            title(app.UIAxes, 'Original Image')
            app.UploadImageButton = uibutton(app.HOMETab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Icon = 'uploadIcon.png';
            app.UploadImageButton.Text = 'Upload Image';
            app.TabGroup = uitabgroup(app.HOMETab);
            app.GammaTab = uitab(app.TabGroup);
            app.GammaTab.Title = 'Gamma';
            app.GammaValueEditFieldLabel = uilabel(app.GammaTab);
            app.GammaValueEditFieldLabel.Text = 'Gamma Value';
            app.GammaValueEditField = uieditfield(app.GammaTab, 'text');
            app.ApplyGammaButton = uibutton(app.GammaTab, 'push');
            app.ApplyGammaButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGammaButtonPushed, true);
            app.ApplyGammaButton.Icon = 'apply.png';
            app.ApplyGammaButton.Text = 'Apply Gamma';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.AddValueEditFieldLabel = uilabel(app.LogTab);
            app.AddValueEditFieldLabel.Text = 'Add Value';
            app.AddValueEditField = uieditfield(app.LogTab, 'text');
            app.ApplyLogButton = uibutton(app.LogTab, 'push');
            app.ApplyLogButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLogButtonPushed, true);
            app.ApplyLogButton.Text = 'Apply Log';
            app.Bright1Tab = uitab(app.TabGroup);
            app.Bright1Tab.Title = 'Bright1';
            app.AddSubEditFieldLabel = uilabel(app.Bright1Tab);
            app.AddSubEditFieldLabel.Text = 'Add/Sub';
            app.AddSubEditField = uieditfield(app.Bright1Tab, 'text');
            app.ApplyAddSubButton = uibutton(app.Bright1Tab, 'push');
            app.ApplyAddSubButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAddSubButtonPushed, true);
            app.ApplyAddSubButton.Icon = 'apply.png';
            app.ApplyAddSubButton.Text = 'Apply Add/Sub';
            app.Bright2Tab = uitab(app.TabGroup);
            app.Bright2Tab.Title = 'Bright2';
            app.MulDevEditFieldLabel = uilabel(app.Bright2Tab);
            app.MulDevEditFieldLabel.Text = 'Mul/Dev';
            app.MulDevEditField = uieditfield(app.Bright2Tab, 'text');
            app.ApplyMulDevButton = uibutton(app.Bright2Tab, 'push');
            app.ApplyMulDevButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMulDevButtonPushed, true);
            app.ApplyMulDevButton.Icon = 'apply.png';
            app.ApplyMulDevButton.Text = 'Apply Mul/Dev';
            app.FourierTransformTab = uitab(app.TabGroup);
            app.FourierTransformTab.Title = 'Fourier Transform';
            app.ButtonGroup = uibuttongroup(app.FourierTransformTab);
            app.FourierTransformButton = uiradiobutton(app.ButtonGroup);
            app.FourierTransformButton.Text = 'Fourier Transform';
            app.FourierTransformButton.Value = true;
            app.InverseFourierTransformButton = uiradiobutton(app.ButtonGroup);
            app.InverseFourierTransformButton.Text = 'Inverse Fourier Transform';
            app.ApplyFourierButton = uibutton(app.FourierTransformTab, 'push');
            app.ApplyFourierButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFourierButtonPushed, true);
            app.ApplyFourierButton.Icon = 'apply.png';
            app.ApplyFourierButton.Text = 'Apply Fourier';
            app.NegativeTab = uitab(app.TabGroup);
            app.NegativeTab.Title = ' Negative';
            app.RGB2NegativeButton = uibutton(app.NegativeTab, 'push');
            app.RGB2NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @RGB2NegativeButtonPushed, true);
            app.RGB2NegativeButton.Text = 'RGB2Negative';
            app.Binary2NegativeButton = uibutton(app.NegativeTab, 'push');
            app.Binary2NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @Binary2NegativeButtonPushed, true);
            app.Binary2NegativeButton.Text = 'Binary2Negative';
            app.CloseButton = uibutton(app.HOMETab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Icon = 'close.png';
            app.CloseButton.Text = 'Close';
            app.RestartButton = uibutton(app.HOMETab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Icon = 'restart.png';
            app.RestartButton.Text = 'Restart';
            app.ResetButton_3 = uibutton(app.HOMETab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.SaveButton_3 = uibutton(app.HOMETab, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.HistogramTab = uitab(app.TabGroup2);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxesinputHisto = uiaxes(app.HistogramTab);
            title(app.UIAxesinputHisto, 'Original Image')
            app.UIAxesOutputHisto = uiaxes(app.HistogramTab);
            title(app.UIAxesOutputHisto, 'Output')
            app.ShowHistogramButton = uibutton(app.HistogramTab, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Icon = 'histoIcon.png';
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.ApplyHistogramEqButton = uibutton(app.HistogramTab, 'push');
            app.ApplyHistogramEqButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHistogramEqButtonPushed2, true);
            app.ApplyHistogramEqButton.Icon = 'hisEq.png';
            app.ApplyHistogramEqButton.Text = 'Apply Histogram Eq';
            app.ContrastStretchingPanel = uipanel(app.HistogramTab);
            app.ContrastStretchingPanel.Title = 'Contrast Stretching';
            app.NewMaxEditFieldLabel = uilabel(app.ContrastStretchingPanel);
            app.NewMaxEditFieldLabel.Text = 'New Max';
            app.NewMaxEditField = uieditfield(app.ContrastStretchingPanel, 'text');
            app.ApplyButton = uibutton(app.ContrastStretchingPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = 'apply.png';
            app.ApplyButton.Text = 'Apply';
            app.NewMinEditFieldLabel = uilabel(app.ContrastStretchingPanel);
            app.NewMinEditFieldLabel.Text = 'New Min';
            app.NewMinEditField = uieditfield(app.ContrastStretchingPanel, 'text');
            app.UploadImageButton_3 = uibutton(app.HistogramTab, 'push');
            app.UploadImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButton_3Pushed, true);
            app.UploadImageButton_3.Icon = 'uploadIcon.png';
            app.UploadImageButton_3.Text = 'Upload Image';
            app.RestartButton_2 = uibutton(app.HistogramTab, 'push');
            app.RestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_2Pushed, true);
            app.RestartButton_2.Icon = 'restart.png';
            app.RestartButton_2.Text = 'Restart';
            app.FiltersTab = uitab(app.TabGroup2);
            app.FiltersTab.Title = 'Filters';
            app.UIAxesOutputFilter = uiaxes(app.FiltersTab);
            title(app.UIAxesOutputFilter, 'Output')
            app.UIAxesinputFilter = uiaxes(app.FiltersTab);
            title(app.UIAxesinputFilter, 'Original Image')
            app.ApplyFilterButton = uibutton(app.FiltersTab, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.TabGroup3 = uitabgroup(app.FiltersTab);
            app.MinFilterTab = uitab(app.TabGroup3);
            app.MinFilterTab.Title = 'Min Filter';
            app.ValueEditFieldLabel = uilabel(app.MinFilterTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.MinFilterTab, 'numeric');
            app.MaxFilterTab = uitab(app.TabGroup3);
            app.MaxFilterTab.Title = 'Max Filter';
            app.ValueEditField_2Label = uilabel(app.MaxFilterTab);
            app.ValueEditField_2Label.Text = 'Value';
            app.ValueEditField_2 = uieditfield(app.MaxFilterTab, 'numeric');
            app.MedianFilterTab = uitab(app.TabGroup3);
            app.MedianFilterTab.Title = 'Median Filter';
            app.ValueEditField_3Label = uilabel(app.MedianFilterTab);
            app.ValueEditField_3Label.Text = 'Value';
            app.ValueEditField_3 = uieditfield(app.MedianFilterTab, 'numeric');
            app.MidPointFilterTab = uitab(app.TabGroup3);
            app.MidPointFilterTab.Title = 'MidPoint Filter';
            app.ValueEditField_4Label = uilabel(app.MidPointFilterTab);
            app.ValueEditField_4Label.Text = 'Value';
            app.ValueEditField_4 = uieditfield(app.MidPointFilterTab, 'numeric');
            app.GaussLowTab = uitab(app.TabGroup3);
            app.GaussLowTab.Title = 'Gauss Low';
            app.RadiusEditField_2Label = uilabel(app.GaussLowTab);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.RadiusEditField_2 = uieditfield(app.GaussLowTab, 'numeric');
            app.GaussHighTab = uitab(app.TabGroup3);
            app.GaussHighTab.Title = 'Gauss High';
            app.RadiusEditFieldLabel = uilabel(app.GaussHighTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.GaussHighTab, 'numeric');
            app.BWLowTab = uitab(app.TabGroup3);
            app.BWLowTab.Title = 'BW Low';
            app.RadiusEditField_3Label = uilabel(app.BWLowTab);
            app.RadiusEditField_3Label.Text = 'Radius';
            app.RadiusEditField_3 = uieditfield(app.BWLowTab, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.BWLowTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.BWLowTab, 'numeric');
            app.BWHighTab = uitab(app.TabGroup3);
            app.BWHighTab.Title = 'BW High';
            app.RadiusEditField_4Label = uilabel(app.BWHighTab);
            app.RadiusEditField_4Label.Text = 'Radius';
            app.RadiusEditField_4 = uieditfield(app.BWHighTab, 'numeric');
            app.OrderEditField_2Label = uilabel(app.BWHighTab);
            app.OrderEditField_2Label.Text = 'Order';
            app.OrderEditField_2 = uieditfield(app.BWHighTab, 'numeric');
            app.IdealLPTab_2 = uitab(app.TabGroup3);
            app.IdealLPTab_2.Title = 'Ideal LP';
            app.RadiusEditField_5Label = uilabel(app.IdealLPTab_2);
            app.RadiusEditField_5Label.Text = 'Radius';
            app.RadiusEditField_5 = uieditfield(app.IdealLPTab_2, 'numeric');
            app.IdealHPTab = uitab(app.TabGroup3);
            app.IdealHPTab.Title = 'Ideal HP';
            app.RadiusEditField_6Label = uilabel(app.IdealHPTab);
            app.RadiusEditField_6Label.Text = 'Radius';
            app.RadiusEditField_6 = uieditfield(app.IdealHPTab, 'numeric');
            app.UploadImageButton_2 = uibutton(app.FiltersTab, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButton_2Pushed, true);
            app.UploadImageButton_2.Icon = 'uploadIcon.png';
            app.UploadImageButton_2.Text = 'Upload Image';
            app.FilterPanel = uipanel(app.FiltersTab);
            app.FilterPanel.Title = 'Filter';
            app.EditFieldmask = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_2 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_3 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_4 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_5 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_6 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_7 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_8 = uieditfield(app.FilterPanel, 'numeric');
            app.EditFieldmask_9 = uieditfield(app.FilterPanel, 'numeric');
            app.ApplyFilterDropDownLabel = uilabel(app.FilterPanel);
            app.ApplyFilterDropDownLabel.Text = 'Apply Filter';
            app.ApplyFilterDropDown = uidropdown(app.FilterPanel);
            app.ApplyFilterDropDown.Items = {'Correlation', 'Point Detection', 'Point Sharpening', 'Weight Filter'};
            app.ApplyFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterDropDownValueChanged, true);
            app.ApplyFilterDropDown.Value = 'Correlation';
            app.LineDetectionDropDownLabel = uilabel(app.FilterPanel);
            app.LineDetectionDropDownLabel.Text = 'Line Detection';
            app.LineDetectionDropDown = uidropdown(app.FilterPanel);
            app.LineDetectionDropDown.Items = {'Left', 'Right', 'Vertical', 'Horizontal'};
            app.LineDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LineDetectionDropDownValueChanged, true);
            app.LineDetectionDropDown.Value = 'Left';
            app.RestartButton_3 = uibutton(app.FiltersTab, 'push');
            app.RestartButton_3.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_3Pushed, true);
            app.RestartButton_3.Icon = 'restart.png';
            app.RestartButton_3.Text = 'Restart';
            app.ResetButton_2 = uibutton(app.FiltersTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.SaveButton_2 = uibutton(app.FiltersTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.NoiseConvertTab = uitab(app.TabGroup2);
            app.NoiseConvertTab.Title = 'Noise & Convert';
            app.UIAxesInputNoise = uiaxes(app.NoiseConvertTab);
            title(app.UIAxesInputNoise, 'Original Image')
            app.UIAxesOutputNoise_2 = uiaxes(app.NoiseConvertTab);
            title(app.UIAxesOutputNoise_2, 'Output ')
            app.Panel = uipanel(app.NoiseConvertTab);
            app.ConvertButton = uibutton(app.Panel, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Icon = 'convertIcon.png';
            app.ConvertButton.Text = 'Convert';
            app.ChosetheProcessDropDownLabel = uilabel(app.Panel);
            app.ChosetheProcessDropDownLabel.Text = 'Chose the Process';
            app.ChosetheProcessDropDown = uidropdown(app.Panel);
            app.ChosetheProcessDropDown.Items = {'RGB2GREY', 'RGB2BINARY', 'GREY2BINARY', '', ''};
            app.ChosetheProcessDropDown.Value = 'RGB2GREY';
            app.TabGroup4 = uitabgroup(app.NoiseConvertTab);
            app.GaussianNoiseTab = uitab(app.TabGroup4);
            app.GaussianNoiseTab.Title = 'Gaussian Noise';
            app.MeuEditFieldLabel = uilabel(app.GaussianNoiseTab);
            app.MeuEditFieldLabel.Text = 'Meu';
            app.MeuEditField = uieditfield(app.GaussianNoiseTab, 'numeric');
            app.SegmaEditFieldLabel = uilabel(app.GaussianNoiseTab);
            app.SegmaEditFieldLabel.Text = 'Segma';
            app.SegmaEditField = uieditfield(app.GaussianNoiseTab, 'numeric');
            app.AddNoiseButton = uibutton(app.GaussianNoiseTab, 'push');
            app.AddNoiseButton.Text = 'Add Noise';
            app.RayLeighNoiseTab = uitab(app.TabGroup4);
            app.RayLeighNoiseTab.Title = 'RayLeigh Noise';
            app.AEditFieldLabel = uilabel(app.RayLeighNoiseTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.RayLeighNoiseTab, 'numeric');
            app.BEditFieldLabel = uilabel(app.RayLeighNoiseTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.RayLeighNoiseTab, 'numeric');
            app.AddNoiseButton_2 = uibutton(app.RayLeighNoiseTab, 'push');
            app.AddNoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_2Pushed, true);
            app.AddNoiseButton_2.Text = 'Add Noise';
            app.GammaNoiseTab = uitab(app.TabGroup4);
            app.GammaNoiseTab.Title = 'Gamma Noise';
            app.AEditField_2Label = uilabel(app.GammaNoiseTab);
            app.AEditField_2Label.Text = 'A';
            app.AEditField_2 = uieditfield(app.GammaNoiseTab, 'numeric');
            app.BEditField_2Label = uilabel(app.GammaNoiseTab);
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.GammaNoiseTab, 'numeric');
            app.AddNoiseButton_3 = uibutton(app.GammaNoiseTab, 'push');
            app.AddNoiseButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_3Pushed, true);
            app.AddNoiseButton_3.Text = 'Add Noise';
            app.UniformNoiseTab = uitab(app.TabGroup4);
            app.UniformNoiseTab.Title = 'Uniform Noise';
            app.AddNoiseButton_4 = uibutton(app.UniformNoiseTab, 'push');
            app.AddNoiseButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_4Pushed, true);
            app.AddNoiseButton_4.Text = 'Add Noise';
            app.AEditField_3Label = uilabel(app.UniformNoiseTab);
            app.AEditField_3Label.Text = 'A';
            app.AEditField_3 = uieditfield(app.UniformNoiseTab, 'numeric');
            app.BEditField_3Label = uilabel(app.UniformNoiseTab);
            app.BEditField_3Label.Text = 'B';
            app.BEditField_3 = uieditfield(app.UniformNoiseTab, 'numeric');
            app.PercEditFieldLabel = uilabel(app.UniformNoiseTab);
            app.PercEditFieldLabel.Text = 'Perc';
            app.PercEditField = uieditfield(app.UniformNoiseTab, 'numeric');
            app.SaltPepperTab = uitab(app.TabGroup4);
            app.SaltPepperTab.Title = 'Salt&Pepper';
            app.PaEditFieldLabel = uilabel(app.SaltPepperTab);
            app.PaEditFieldLabel.Text = 'Pa';
            app.PaEditField = uieditfield(app.SaltPepperTab, 'numeric');
            app.PbEditFieldLabel = uilabel(app.SaltPepperTab);
            app.PbEditFieldLabel.Text = 'Pb';
            app.PbEditField = uieditfield(app.SaltPepperTab, 'numeric');
            app.AddNoiseButton_5 = uibutton(app.SaltPepperTab, 'push');
            app.AddNoiseButton_5.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_5Pushed, true);
            app.AddNoiseButton_5.Text = 'Add Noise';
            app.UploadImageButton_4 = uibutton(app.NoiseConvertTab, 'push');
            app.UploadImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButton_4Pushed, true);
            app.UploadImageButton_4.Icon = 'uploadIcon.png';
            app.UploadImageButton_4.Text = 'Upload Image';
            app.RestartButton_4 = uibutton(app.NoiseConvertTab, 'push');
            app.RestartButton_4.ButtonPushedFcn = createCallbackFcn(app, @RestartButton_4Pushed, true);
            app.RestartButton_4.Icon = 'restart.png';
            app.RestartButton_4.Text = 'Restart';
            app.ResetButton = uibutton(app.NoiseConvertTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.NoiseConvertTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end